classdef matlab_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FilesSelectedListBox       matlab.ui.control.ListBox
        FilesSelectedListBoxLabel  matlab.ui.control.Label
        EDFDeIdentifierLabel       matlab.ui.control.Label
        DeIdentifyButton           matlab.ui.control.Button
        SelectFilesButton          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFilesButton
        function SelectFilesButtonPushed(app, event)
            [file,location] = uigetfile('*.edf','Select EDF Files','MultiSelect','on');
if ~isequal(file,0)
    if ~iscell(location)
        location = {location};
        file = {file};
    end

  app.FilesSelectedListBox.Items = fullfile(location,file);
end
        end

        % Button pushed function: DeIdentifyButton
        function DeIdentifyButtonPushed(app, event)
            for ii = 1:length(app.FilesSelectedListBox.Items)
            EDF_deidentify(app.FilesSelectedListBox.Items{ii})
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelectFilesButton = uibutton(app.UIFigure, 'push');
            app.SelectFilesButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesButtonPushed, true);
            app.SelectFilesButton.Text = 'Select Files';
            app.DeIdentifyButton = uibutton(app.UIFigure, 'push');
            app.DeIdentifyButton.ButtonPushedFcn = createCallbackFcn(app, @DeIdentifyButtonPushed, true);
            app.DeIdentifyButton.Text = 'De-Identify';
            app.EDFDeIdentifierLabel = uilabel(app.UIFigure);
            app.EDFDeIdentifierLabel.Text = 'EDF De-Identifier';
            app.FilesSelectedListBoxLabel = uilabel(app.UIFigure);
            app.FilesSelectedListBoxLabel.Text = 'Files Selected';
            app.FilesSelectedListBox = uilistbox(app.UIFigure);
            app.FilesSelectedListBox.Items = {};
            app.FilesSelectedListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = matlab_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end